classdef proje < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SortingTab                     matlab.ui.container.Tab
        CreateArrayButton              matlab.ui.control.Button
        InsertionSortButton            matlab.ui.control.Button
        BubbleSortButton               matlab.ui.control.Button
        QuickSortButton                matlab.ui.control.Button
        HeapSortButton                 matlab.ui.control.Button
        BucketSortButton               matlab.ui.control.Button
        CountingSortButton             matlab.ui.control.Button
        RadixSortButton                matlab.ui.control.Button
        MergeSortButton                matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        RangeKnobLabel                 matlab.ui.control.Label
        RangeKnob                      matlab.ui.control.Knob
        NumberofElementsKnobLabel      matlab.ui.control.Label
        NumberofElementsKnob           matlab.ui.control.Knob
        Button_6                       matlab.ui.control.Button
        Button_7                       matlab.ui.control.Button
        Button_8                       matlab.ui.control.Button
        Button_9                       matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        ComparisionTabs                matlab.ui.container.Tab
        MaxRangeEditField_2            matlab.ui.control.NumericEditField
        NumberofElementsEditField_2    matlab.ui.control.NumericEditField
        InsertionSortsecEditField      matlab.ui.control.NumericEditField
        MergeSortsecEditField          matlab.ui.control.NumericEditField
        BubbleSortsecEditField         matlab.ui.control.NumericEditField
        QuickSortsecEditField          matlab.ui.control.NumericEditField
        HeapSortsecEditField           matlab.ui.control.NumericEditField
        CountingSortsecEditField       matlab.ui.control.NumericEditField
        RadixSortsecEditField          matlab.ui.control.NumericEditField
        BucketSortsecEditField         matlab.ui.control.NumericEditField
        ResultsLabel                   matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        SortedArrayTextAreaLabel       matlab.ui.control.Label
        SortedArrayTextArea            matlab.ui.control.TextArea
        IterationEditFieldLabel        matlab.ui.control.Label
        IterationEditField             matlab.ui.control.NumericEditField
        MaxRangeEditFieldLabel_2       matlab.ui.control.Label
        NumberofElementsEditFieldLabel_2  matlab.ui.control.Label
        MaxRangeEditFieldLabel_3       matlab.ui.control.Label
        NumberofElementsEditFieldLabel_3  matlab.ui.control.Label
        InsertionSortsecLabel          matlab.ui.control.Label
        MergeSortsecEditFieldLabel     matlab.ui.control.Label
        BubbleSortsecEditFieldLabel    matlab.ui.control.Label
        QuickSortsecEditFieldLabel     matlab.ui.control.Label
        HeapSortsecEditFieldLabel      matlab.ui.control.Label
        CountingSortsecEditFieldLabel  matlab.ui.control.Label
        RadixSortsecEditFieldLabel     matlab.ui.control.Label
        BucketSortsecEditFieldLabel    matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        RandomizedTab                  matlab.ui.container.Tab
        FindButton                     matlab.ui.control.Button
        title3                         matlab.ui.control.Label
        NumberofElementsEditField_3    matlab.ui.control.NumericEditField
        MaxRangeEditField_3            matlab.ui.control.NumericEditField
        SearchedindexEditField         matlab.ui.control.NumericEditField
        AnswerTextAreaLabel            matlab.ui.control.Label
        AnswerTextArea                 matlab.ui.control.TextArea
        ArraySizeKnob_2                matlab.ui.control.Knob
        MaxRangeEditField_3Label       matlab.ui.control.Label
        ArraySizeKnob_3                matlab.ui.control.Knob
        NumberofElementsEditField_3Label  matlab.ui.control.Label
        ArrayTextAreaLabel             matlab.ui.control.Label
        ArrayTextArea                  matlab.ui.control.TextArea
        ArraySizeKnob_4                matlab.ui.control.Knob
        SearchedindexEditFieldLabel    matlab.ui.control.Label
        FibonacciTab                   matlab.ui.container.Tab
        SortButton                     matlab.ui.control.Button
        FibonacciButton                matlab.ui.control.Button
        BinarysearchinfibonacciButton  matlab.ui.control.Button
        BINARYSEARCHLabel              matlab.ui.control.Label
        FIBONACCILabel                 matlab.ui.control.Label
        AnswerButton                   matlab.ui.control.Button
        RangeEditField                 matlab.ui.control.NumericEditField
        ElementsEditField              matlab.ui.control.NumericEditField
        RandomarrayTextAreaLabel       matlab.ui.control.Label
        RandomarrayTextArea            matlab.ui.control.TextArea
        NumberEditFieldLabel           matlab.ui.control.Label
        NumberEditField                matlab.ui.control.NumericEditField
        SortedarrayTextAreaLabel       matlab.ui.control.Label
        SortedarrayTextArea            matlab.ui.control.TextArea
        AnswerTextArea_2Label          matlab.ui.control.Label
        AnswerTextArea_2               matlab.ui.control.TextArea
        AnswerTextArea_3Label          matlab.ui.control.Label
        AnswerTextArea_3               matlab.ui.control.TextArea
        NthnumberofFibonacciEditFieldLabel  matlab.ui.control.Label
        NthnumberofFibonacciEditField  matlab.ui.control.NumericEditField
        FibonacciEditFieldLabel        matlab.ui.control.Label
        FibonacciEditField             matlab.ui.control.NumericEditField
        AnswerTextArea_4Label          matlab.ui.control.Label
        AnswerTextArea_4               matlab.ui.control.TextArea
        ArraySizeKnob_5                matlab.ui.control.Knob
        ArraySizeKnob_6                matlab.ui.control.Knob
        RangeEditFieldLabel            matlab.ui.control.Label
        ElementsEditFieldLabel         matlab.ui.control.Label
        HelpTab                        matlab.ui.container.Tab
        Button_5                       matlab.ui.control.Button
        StudentnameEgeAlpTrkyenerNumber170403031ProfessorvOLLabel  matlab.ui.control.Label
        Thisprogramhas4tabs1SortingThistabLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        random_array=[]; % Description
        run_numbers=[];
        B =[];
        pauseS = 1 ;
        speed = 1;
        ins_s_t = zeros(); %comparision tab
        bub_s_t = zeros();%comparision tab
        merg_s_t = zeros();%comparision tab
        buc_s_t = zeros();%comparision tab
        rad_s_t = zeros();%comparision tab
        cont_s_t = zeros();%comparision tab
        quick_s_t = zeros();%comparision tab
        heap_s_t = zeros();%comparision tab
        answer1 = [];%fibonacci tab
        searched_number = [];%fibonacci tab
        sorted_random_array1 = [];%fibo tab
    end
     % Description


    methods (Access = private)
                %%%%%%%%%%%%%% BUCKET SORT%%%%%%%%%%%%%%%%%

        function array = bucketsort(app,array)
                        %% Constants
                max_array = max(array); %find max array
                bucket = zeros(1,max_array+1); %create bucket
               %% For Loop
                for j = 1:numel(array)
                    bucket(array(j)) = bucket(array(j)) + 1;
                    
                end
                index = 1;
                for i = 1:max_array+1
                    for j = 1:bucket(i) 
                        %% %%speed%% %%
                            if app.speed ==1 
                                 elseif app.speed==2
                                      pause(1);
                                elseif app.speed ==3
                                   pause(1.5)
                                elseif app.speed==4
                                     pause(2)
                            end
                       %% %%speed%% %%

                        %% %%pause%% %%
                            if app.pauseS == 0
                            while(1)
                                pause(0.001);
                                if app.pauseS == 1
                                    break;
                                end
                            end
                            end
                        array(index) = i;
                        index = index + 1;
                        [array] = array;
                        b=bar(app.UIAxes,array,'y','LineWidth',3);
                        b.FaceColor = 'flat';
                         b.CData(j,:) = [.0 0 .0];

                        text(app.UIAxes,1:length(array),array,num2str(array'),'vert','bottom','horiz','center'); 
                        box(app.UIAxes,'off')


                        drawnow 
                        if ~isvalid(b)
                            break
                        end
                    end
                 
                end
        end
                %%%%%%%%%%%%%% HEAP SORT%%%%%%%%%%%%%%%%%

        function array = heapsort(app,array)
            heap_size = numel(array); %array size
            array = build_max_heap(app,array,heap_size); %calling function
            for i = numel(array):-1:2 
                %% %%speed%% %%
                            if app.speed ==1 
                                 elseif app.speed==2
                                      pause(1);
                                elseif app.speed ==3
                                   pause(1.5)
                                elseif app.speed==4
                                     pause(2)
                            end
                       %% %%speed%% %%

                        %% %%pause%% %%
                            if app.pauseS == 0
                            while(1)
                                pause(0.001);
                                if app.pauseS == 1
                                    break;
                                end
                            end
                            end
                array([i 1]) = array([1 i]); %swap
                heap_size = heap_size - 1; 
                array = max_heapify(app,array,heap_size,1);
                b=bar(app.UIAxes,array,'y','LineWidth',3);
                b.FaceColor = 'flat';
                b.CData(i,:) = [.0 0 .0];
             %   b.CData(i+1,:) = [.0 0 .0];
                text(app.UIAxes,1:length(array),array,num2str(array'),'vert','bottom','horiz','center'); 
                box(app.UIAxes,'off')

                
                drawnow 
                if ~isvalid(b)
                     break
                 end
            end
        end

        function array = build_max_heap(app,array,heap_size) 
            for i = floor(heap_size/2):-1:1
                array = max_heapify(app,array,heap_size,i); %call function max_heapify
                
            end
        end
        
        function array = max_heapify(app,array,heap_size,i)
            l = left(app,i); %lef side
            r = right(app,i); %right side
            if l <= heap_size && array(l) > array(i)
                largest = l; %make largest equal to 1
            else
                largest = i; %make largest equal to i
            end
            if r <= heap_size && array(r) > array(largest)
                largest = r; %make largest equal to r
            end
            if largest ~= i %if largest is not equal i
                array([largest i]) = array([i largest]); %swap between array(largest) and array(i)
                array = max_heapify(app,array,heap_size,largest); %call function max_heapify
            end
        end
        
        function l = left(~,i)
            l = 2 * i;
        
        end
        
        function r = right(~,i)
            r = 2 * i + 1;
        
        end
            
        
        %%%% COUNTING %%%%%%%%%%%%%%%%%
        function B = countingsort(app,A,max_value)
                        %% Constants
            length_array = numel(A);
            C = zeros(max_value,1);
            %% For Loop
            for j = 1:length_array
                C(A(j)) = C(A(j)) +1; %add 1 to index
                
            end
            
            for i = 2:max_value
                 C(i) = C(i) + C(i-1); %i index equal to i + i-1 index
                 
                
            end
            B = nan(length_array,1);
            
            for j = length_array:-1:1
                %% %%speed%% %%
                            if app.speed ==1 
                                 elseif app.speed==2
                                      pause(1);
                                elseif app.speed ==3
                                   pause(1.5)
                                elseif app.speed==4
                                     pause(2)
                            end
                       %% %%speed%% %%

                        %% %%pause%% %%
                            if app.pauseS == 0
                            while(1)
                                pause(0.001);
                                if app.pauseS == 1
                                    break;
                                end
                            end
                            end
                B(C(A(j))) = A(j);
                C(A(j)) = C(A(j)) -1 ;
                b=bar(app.UIAxes,B,'y','LineWidth',3);
                b.FaceColor = 'flat';

                box(app.UIAxes,'off')
                
                
                 drawnow                 

                 if ~isvalid(b)
                    break
                 end
              
            end

        end
                %%%%%%%%%%%%%% RADIX SORT%%%%%%%%%%%%%%%%%

        function array = radixsort(app,array)
        %% Constants
        max_array = max(array);
        base = 1;
        
        %% While Loop
        while max_array/base > 1 %while max number greater than 1
            array = countingsort2(app,array,base); %call counting sort
            base = base * 10; %base multiply by 1


        end
        function B = countingsort2(app,array,base) %counting sort

            if app.speed ==1
                         elseif app.speed==2
                              pause(1);
                        elseif app.speed ==3
                           pause(1.5)
                        elseif app.speed==4
                             pause(2)
             end
               
                if app.pauseS == 0
                            while(1)
                                pause(0.001);
                                if app.pauseS == 1
                                    break;
                                end
                            end
                end
             C = zeros(1,11);
             B = zeros(1,numel(array));
             for j = 1:numel(array)
                 C(rem(floor(array(j)/base),10)+1) = C(rem(floor(array(j)/base),10)+1) + 1;
             end
             for i = 2:11
                C(i) = C(i) + C(i-1);
             end
             for j = numel(array):-1:1
                 B(C(rem(floor(array(j)/base),10)+1)) = array(j);
                 C(rem(floor(array(j)/base),10)+1) = C(rem(floor(array(j)/base),10)+1) - 1;
                
                  b=bar(app.UIAxes,B,'y','LineWidth',3);
                  b.FaceColor = 'flat';

                    box(app.UIAxes,'off');
                  drawnow 
                 
                 
             end
         end
        
        
        end
             
         
               %%%%%%%%%%%%%% INSERTION SORT%%%%%%%%%%%%%%%%%

        
        function array = insertionsort(app,array)

        for j=2:length(array)%for the numbers from 2 to len(array)

            key=array(j);%save A[j element to a temporary constant.
        
            i=j-1;
            
           % move the elements of array A if
           %it is greater than key.
            while i>0 && array(i)>key
               
                    if app.speed ==1
                         elseif app.speed==2
                              pause(1);
                        elseif app.speed ==3
                           pause(1.5)
                        elseif app.speed==4
                             pause(2)
                    end
               
                if app.pauseS == 0
                            while(1)
                                pause(0.001);
                                if app.pauseS == 1
                                    break;
                                end
                            end
                end
                array(i+1)=array(i);
                i=i-1;
                array(i+1)=key;
               
        
                b=bar(app.UIAxes,array,'y','LineWidth',3);
                b.FaceColor = 'flat';
                b.CData(j,:) = [.0 0 .0];
                b.CData(i+1,:) = [.0 0 .0];

                text(app.UIAxes,1:length(array),array,num2str(array'),'vert','bottom','horiz','center'); 
               
                
                drawnow 
            

                 if ~isvalid(b)
                     break
                 end
            end
        end
        
            
        end
        
                %%%%%%%%%%%%%% BUBBLE SORT%%%%%%%%%%%%%%%%%

        function [array] = bubblesort(app,array)
            for i=1:length(array)-1
                for j=2:1:length(array)

                    if array(j)<array(j-1)
                      %% %%speed%% %%
                            if app.speed ==1 
                                 elseif app.speed==2
                                      pause(1);
                                elseif app.speed ==3
                                   pause(1.5)
                                elseif app.speed==4
                                     pause(2)
                            end
                       %% %%speed%% %%

                        %% %%pause%% %%
                            if app.pauseS == 0
                            while(1)
                                pause(0.001);
                                if app.pauseS == 1
                                    break;
                                end
                            end
                            end
                        key=array(j-1);%saving our number in a constant
                        array(j-1)=array(j);%replacing numbers in the array
                        array(j)=key;%replacing numbers in the array
                     
                        %answer = bar(app.UIAxes,array,'r');
                        b=bar(app.UIAxes,array,'y','LineWidth',3);
                        b.FaceColor = 'flat';
                        b.CData(j,:) = [.0 0 .0];
                        b.CData(j-1,:) = [.0 0 .0];
                        text(app.UIAxes,1:length(array),array,num2str(array'),'vert','bottom','horiz','center'); 
                        box(app.UIAxes,'off')
                        drawnow 


                         if ~isvalid(b)
                             break
                         end
                         
                    end
                end
            end
        end
        
             %%%%%%%%%%%%%% QUICK SORT%%%%%%%%%%%%%%%%%

        function [arr] = quicksort(app,arr,low,high)
            if low < high %if last item bigger than first item
                 [arr,pivot] = partition(app,arr, low, high); %call function partition
                 arr = quicksort(app,arr,low,pivot-1); %recursive calling the itself.
                 arr = quicksort(app,arr,pivot+1,high); %recursive calling the itself.
            end

        end
        
        function  [arr,i]  = partition(app,arr, low, high)
            pivot = arr(high); %pivot is last of the array

            i = low -1; 
            for j = low:high-1 
                
                if arr(j)<=pivot %if pivot bigger than array's j item
                    i = i+1; %i is increased by 1
                    if i~=j %if i is not equal j
                            key = arr(i); %swap arr(i) and arr(j)
                            arr(i) = arr(j);
                            arr(j) = key;
                            
                           
                    end
               
                b=bar(app.UIAxes,arr,'y','LineWidth',3);
                b.FaceColor = 'flat';
                b.CData(j,:) = [.0 0 .0];
                b.CData(high,:) = [.0 0 .0];             
                text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center'); 
                box(app.UIAxes,'off')


                drawnow                             
                if ~isvalid(b)
                     break
                end
                end
               
            end
                i = i+1; %i is increased by 1
                key = arr(i); %swap arr(i) and arr(high)
                arr(i) = arr(high);
                arr(high) = key;
                
                b=bar(app.UIAxes,arr,'y','LineWidth',3);

                b.FaceColor = 'flat';
                b.CData(i,:) = [.0 0 .0];
                b.CData(high,:) = [.0 0 .0];    
                text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center'); 
        end
        %%%%%%%%%%%%%% MERGE SORT%%%%%%%%%%%%%%%%%
        function array=merge_sort(app,array)
            array=mergesort(app,array,1,length(array));

        end
        function array = mergesort(app,array,first,last)%dividing the list into 2 halfes.
        if first < last
            mid = floor((first + last)/2); %finding middle index.
            array = mergesort(app,array,first,mid);
            array = mergesort(app,array,mid + 1,last);
            array = merge(app,array,first,mid,last); %combining sorted lists.
        end
        end
        
        function array = merge(app,array, first, mid, last)
        n1 = mid - first + 1; 
        n2 = last - mid;
        L = zeros(1, n1+1); %left side of list.
        R = zeros(1, n2+1); %right side of list.
        for i=1:n1
            L(i) = array(first+ i - 1);    
        end
        for j=1:n2
            R(j) = array(mid+j);
        end
        L(n1+1) = inf; %adding the last index  infinite number.
        R(n2+1) = inf;%adding the last index infinite number.
        i=1;
        j=1;
        
        for k=first:last
            %% %%speed%% %%
            if app.speed ==1 
                 elseif app.speed==2
                      pause(1);
                elseif app.speed ==3
                   pause(1.5)
                elseif app.speed==4
                     pause(2)
            end
       %% %%speed%% %%

        %% %%pause%% %%
            if app.pauseS == 0
            while(1)
                pause(0.001);
                if app.pauseS == 1
                    break;
                end
            end
            end
           
            if L(i)<=R(j) %comparison between righ and left side of lists and
            array(k)=L(i); %ordering them.
            i=i+1;
            
            else
                array(k)=R(j);
                j=j+1;
            end
            b=bar(app.UIAxes,array,'y','LineWidth',3);
            b.FaceColor = 'flat';
            b.CData(k,:) = [.0 0 .0];

            text(app.UIAxes,1:length(array),array,num2str(array'),'vert','bottom','horiz','center'); 
            box(app.UIAxes,'off')


            drawnow 
        end 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateArrayButton
        function CreateArrayButtonPushed(app, event)
             range_number =app.RangeKnob.Value(); %define range
             range_number = floor(range_number);
             app.run_numbers = app.NumberofElementsKnob.Value(); %define number of elements    
             app.run_numbers = floor(app.run_numbers);
             if range_number<0 
                 range_number = -range_number;
             end
             if app.run_numbers<0 
                 app.run_numbers = -app.run_numbers;
             end
             
             if app.run_numbers > range_number
                 msgbox("Array size must be greater than number of elements.");
             else
                 app.random_array = randperm(range_number,app.run_numbers); %create random array

                 bar(app.UIAxes,app.random_array,'r','FaceColor',[0 .0 .0]); %create bars
                 text(app.UIAxes,1:length(app.random_array),app.random_array,num2str(app.random_array'),'vert','bottom','horiz','center'); 
                 box(app.UIAxes,'off')
             end
             
          
        end

        % Button pushed function: InsertionSortButton
        function InsertionSortButtonPushed(app, event)
              insertionsort(app,app.random_array);

        
        end

        % Button pushed function: QuickSortButton
        function QuickSortButtonPushed(app, event)
             quicksort(app,app.random_array,1,length(app.random_array))
        end

        % Button pushed function: HeapSortButton
        function HeapSortButtonPushed(app, event)
            heapsort(app,app.random_array);
        end

        % Button pushed function: BucketSortButton
        function BucketSortButtonPushed(app, event)
            bucketsort(app,app.random_array);
            
        end

        % Button pushed function: CountingSortButton
        function CountingSortButtonPushed3(app, event)
            max_value=max(app.random_array);
            countingsort(app,app.random_array,max_value);
            
            
        end

        % Button pushed function: BubbleSortButton
        function BubbleSortButtonPushed(app, event)
             bubblesort(app,app.random_array);

            
        end

        % Button pushed function: RadixSortButton
        function RadixSortButtonPushed(app, event)
            radixsort(app,app.random_array);
        end

        % Button pushed function: MergeSortButton
        function MergeSortButtonPushed(app, event)
            merge_sort(app,app.random_array);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
        end

        % Callback function
        function SpeedSliderValueChanged(app, event)
            app.SpeedSlider.Value;
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
          
            if app.pauseS== 1
              app.pauseS=0;
            else
              app.pauseS=1;
            end
                

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.speed ==1
                 app.speed=2;
            elseif app.speed==2
                app.speed=3;
            elseif app.speed==3
                app.speed=4;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.speed==2
               app.speed=1;
            elseif app.speed ==3
               app.speed=2;
            elseif app.speed ==4
               app.speed=3;
            end
        end

        % Size changed function: ComparisionTabs
        function ComparisionTabsSizeChanged(app, event)
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            rng('default');
             rng(1721);    
             range_number = app.MaxRangeEditField_2.Value; %define range
             run_numbers1 = app.NumberofElementsEditField_2.Value; %define number of elements
             iteration_num = app.IterationEditField.Value;
             cla(app.UIAxes);
            
             for j = run_numbers1:10:iteration_num
                for i=1:30
                       
                                   
                       random_array1 = randperm(range_number,j); %create random array
                  
                       max_random_array = max(random_array1); %find the max value
                       
                       
                       %insertion and take time
                       tic;
                       insertion_sort(random_array1);
                       insertion_sort_time = toc;
                      
                       app.ins_s_t(i,j) = insertion_sort_time;
                      
                       sum_insertion_sort = sum(app.ins_s_t);
                       ave_ins_sort = sum_insertion_sort / 30 ; %take ave
                       
                       %**************************************
                       %bubble sort and take time
                       tic;
                       bubble_sort(random_array1);
                       bubble_sort_time = toc;
                       
                       app.bub_s_t(i,j) = bubble_sort_time;
                       sum_bubble_sort = sum(app.bub_s_t);
                       ave_bub_sort = sum_bubble_sort / 30 ; %take ave
                       
                       %**************************************
                      %merge sort and take time
                       
                       tic;
                       merge_sorti(random_array1);
                       merge_sort_time = toc;
                       
                       app.merg_s_t(i,j) = merge_sort_time;
                       sum_merge_sort = sum(app.merg_s_t);
                       ave_merg_sort = sum_merge_sort / 30;%take ave
                       %**************************************
                       %bucket sort and take time
                       tic;
                       bucket_sort(random_array1);
                       bucket_sort_time = toc;
                       
                       app.buc_s_t(i,j) = bucket_sort_time;
                       sum_bucket_sort = sum(app.buc_s_t);
                       ave_buc_sort = sum_bucket_sort / 30 ;%take ave
                       %**************************************
                       %radix sort and take time
                       tic;
                       radix_sort(random_array1);
                       radix_sort_time = toc;
                       
                       app.rad_s_t(i,j) = radix_sort_time;
                       sum_radix_sort = sum(app.rad_s_t);
                       ave_rad_sort = sum_radix_sort / 30 ;%take ave
                       %**************************************
                       %counting sort and take time
                       tic;
                       counting_sort(random_array1,max_random_array);
                       counting_sort_time = toc;
                       
                       app.cont_s_t(i,j) = counting_sort_time;
                       sum_counting_sort = sum(app.cont_s_t);
                       ave_cont_sort = sum_counting_sort / 30;%take ave
                       %**************************************
                       %quick sort and take time
                       tic;
                       sorted_array = quicksorti(random_array1,1,length(random_array1));
                       quick_sort_time = toc;
                       
                       app.quick_s_t(i,j) = quick_sort_time;
                       sum_quick_sort = sum(app.quick_s_t);
                       ave_quick_sort = sum_quick_sort / 30;%take ave
                       %**************************************
                       %heap sort and take time 
                       tic;
                       heap_sort(random_array1);
                       heap_sort_time = toc;
                       
                       app.heap_s_t(i,j) = heap_sort_time;
                       sum_heap_sort = sum(app.heap_s_t);
                       ave_heap_sort = sum_heap_sort / 30;%take ave
                       %**************************************


                end
            

                hold(app.UIAxes_2,"on"); 
            
                plot(app.UIAxes_2,j,ave_ins_sort(1,j),'rs',j,ave_bub_sort(1,j),"bv",j,ave_merg_sort(1,j),"kx",j,ave_buc_sort(1,j),"mp",j,ave_rad_sort(1,j),"rd",j,ave_cont_sort(1,j),"g+",j,ave_quick_sort(1,j),"ch",j,ave_heap_sort(1,j),"b."); %plot graph
                legend(app.UIAxes_2,"insertion sort","bubble sort","merge sort","bucket sort","radix sort","counting sort","quick sort","heap sort");
             end
             
             app.InsertionSortsecEditField.Value = ave_ins_sort(length(random_array1)); %display the time
             app.MergeSortsecEditField.Value = ave_merg_sort(length(random_array1));%display the time
             app.BubbleSortsecEditField.Value = ave_bub_sort(length(random_array1));%display the time
             app.QuickSortsecEditField.Value = ave_quick_sort(length(random_array1));%display the time
             app.HeapSortsecEditField.Value = ave_heap_sort(length(random_array1));%display the time
             app.CountingSortsecEditField.Value = ave_cont_sort(length(random_array1));%display the time
             app.RadixSortsecEditField.Value = ave_rad_sort(length(random_array1));%display the time
             app.BucketSortsecEditField.Value = ave_buc_sort(length(random_array1));%display the time


             app.SortedArrayTextArea.Value = num2str(sorted_array);%converting to string and display sorted array
        
        end

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
            range_number = round(app.MaxRangeEditField_3.Value); %define range
            run_numbers1 = round(app.NumberofElementsEditField_3.Value); %define number of elements
            random_array1 = randperm(range_number,run_numbers1); %create random array
            sorted_random_array = sort(random_array1); %sorting the random array
            app.ArrayTextArea.Value = num2str(sorted_random_array);
            searched_num = round(app.SearchedindexEditField.Value);
            answer = rand_select(sorted_random_array,1,length(sorted_random_array),searched_num);
            app.AnswerTextArea.Value = num2str(answer);
        end

        % Button pushed function: SortButton
        function SortButtonPushed(app, event)
            range_number = app.RangeEditField.Value; %define range
            app.run_numbers = app.ElementsEditField.Value; %define number of elements
            if app.run_numbers > range_number
                    app.AnswerTextArea_2.Value = "Range should be bigger than number of elements.";
            
        else
            app.random_array = floor(randperm(round(range_number),round(app.run_numbers))); %create random array
            app.sorted_random_array1 = sort(app.random_array); %sorting the random array
          
            app.RandomarrayTextArea.Value = num2str(app.random_array); %print random array to text area
            app.SortedarrayTextArea.Value = num2str(app.sorted_random_array1);%print sorted array to text area

            end
        end

        % Button pushed function: AnswerButton
        function AnswerButtonPushed(app, event)
            app.searched_number = app.NumberEditField.Value; %which number
    
            app.answer1 = binary_search2(app.sorted_random_array1,app.searched_number); %calling function
           
            app.AnswerTextArea_2.Value = num2str(app.answer1);%print answer to text area
        end

        % Button pushed function: FibonacciButton
        function FibonacciButtonPushed(app, event)
            fibonacci_number = app.NthnumberofFibonacciEditField.Value; %getting number from user
            app.answer1 = fibonacci(fibonacci_number); %calling function
            app.AnswerTextArea_3.Value = num2str(app.answer1); %print answer to text area
        end

        % Button pushed function: BinarysearchinfibonacciButton
        function BinarysearchinfibonacciButtonPushed(app, event)
            fibonacci_element = app.FibonacciEditField.Value; %getting number from user
            app.answer1 = fibonacci_binary(fibonacci_element);%calling the function
            app.AnswerTextArea_4.Value = num2str(app.answer1);%print answer to text area
        end

        % Value changed function: NumberofElementsKnob
        function NumberofElementsKnobValueChanged(app, event)
            %value = app.NumberofElementsKnob.Value;
            
        end

        % Value changed function: RangeKnob
        function RangeKnobValueChanged(app, event)
            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
        
                    app.RangeKnob.Limits  = app.RangeKnob.Limits- 100;
        end

        % Callback function
        function Button_11Pushed(app, event)
            
        end

        % Button pushed function: Button_7
        function Button_12Pushed(app, event)
                    app.RangeKnob.Limits  = app.RangeKnob.Limits +100;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
                   app.NumberofElementsKnob.Limits  = app.NumberofElementsKnob.Limits-100;

        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
                    app.NumberofElementsKnob.Limits  = app.NumberofElementsKnob.Limits+100;

        end

        % Value changed function: ArraySizeKnob_2
        function ArraySizeKnob_2ValueChanged(app, event)
            app.MaxRangeEditField_3.Value = app.ArraySizeKnob_2.Value;
            
        end

        % Value changed function: ArraySizeKnob_3
        function ArraySizeKnob_3ValueChanged(app, event)
                app.NumberofElementsEditField_3.Value = app.ArraySizeKnob_3.Value;

        end

        % Value changed function: ArraySizeKnob_4
        function ArraySizeKnob_4ValueChanged(app, event)
            app.SearchedindexEditField.Value = app.ArraySizeKnob_4.Value;
            
        end

        % Value changed function: ArraySizeKnob_5
        function ArraySizeKnob_5ValueChanged(app, event)
            app.RangeEditField.Value = app.ArraySizeKnob_5.Value;
            
        end

        % Value changed function: ArraySizeKnob_6
        function ArraySizeKnob_6ValueChanged(app, event)
             app.ElementsEditField.Value = app.ArraySizeKnob_6.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SortingTab = uitab(app.TabGroup);
            app.SortingTab.Title = 'Sorting';
            app.CreateArrayButton = uibutton(app.SortingTab, 'push');
            app.CreateArrayButton.ButtonPushedFcn = createCallbackFcn(app, @CreateArrayButtonPushed, true);
            app.CreateArrayButton.Text = 'Create Array';
            app.InsertionSortButton = uibutton(app.SortingTab, 'push');
            app.InsertionSortButton.ButtonPushedFcn = createCallbackFcn(app, @InsertionSortButtonPushed, true);
            app.InsertionSortButton.Text = 'Insertion Sort';
            app.BubbleSortButton = uibutton(app.SortingTab, 'push');
            app.BubbleSortButton.ButtonPushedFcn = createCallbackFcn(app, @BubbleSortButtonPushed, true);
            app.BubbleSortButton.Text = 'Bubble Sort';
            app.QuickSortButton = uibutton(app.SortingTab, 'push');
            app.QuickSortButton.ButtonPushedFcn = createCallbackFcn(app, @QuickSortButtonPushed, true);
            app.QuickSortButton.Text = 'Quick Sort';
            app.HeapSortButton = uibutton(app.SortingTab, 'push');
            app.HeapSortButton.ButtonPushedFcn = createCallbackFcn(app, @HeapSortButtonPushed, true);
            app.HeapSortButton.Text = 'Heap Sort';
            app.BucketSortButton = uibutton(app.SortingTab, 'push');
            app.BucketSortButton.ButtonPushedFcn = createCallbackFcn(app, @BucketSortButtonPushed, true);
            app.BucketSortButton.Text = 'Bucket  Sort';
            app.CountingSortButton = uibutton(app.SortingTab, 'push');
            app.CountingSortButton.ButtonPushedFcn = createCallbackFcn(app, @CountingSortButtonPushed3, true);
            app.CountingSortButton.Text = 'Counting Sort';
            app.RadixSortButton = uibutton(app.SortingTab, 'push');
            app.RadixSortButton.ButtonPushedFcn = createCallbackFcn(app, @RadixSortButtonPushed, true);
            app.RadixSortButton.Text = 'Radix Sort';
            app.MergeSortButton = uibutton(app.SortingTab, 'push');
            app.MergeSortButton.ButtonPushedFcn = createCallbackFcn(app, @MergeSortButtonPushed, true);
            app.MergeSortButton.Text = 'Merge Sort';
            app.Button = uibutton(app.SortingTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '1d5ebd5a-7bef-4aa4-ab48-c893eebc041e.png';
            app.Button.Text = '';
            app.Button_4 = uibutton(app.SortingTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'resume.png';
            app.Button_4.Text = '';
            app.Button_3 = uibutton(app.SortingTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'speed down.png';
            app.Button_3.Text = '';
            app.Button_2 = uibutton(app.SortingTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'speed up.png';
            app.Button_2.Text = '';
            app.RangeKnobLabel = uilabel(app.SortingTab);
            app.RangeKnobLabel.Text = 'Range';
            app.RangeKnob = uiknob(app.SortingTab, 'continuous');
            app.RangeKnob.Limits = [0 1000];
            app.RangeKnob.ValueChangedFcn = createCallbackFcn(app, @RangeKnobValueChanged, true);
            app.RangeKnob.Value = 100;
            app.NumberofElementsKnobLabel = uilabel(app.SortingTab);
            app.NumberofElementsKnobLabel.Text = 'Number of Elements';
            app.NumberofElementsKnob = uiknob(app.SortingTab, 'continuous');
            app.NumberofElementsKnob.Limits = [1 100];
            app.NumberofElementsKnob.ValueChangedFcn = createCallbackFcn(app, @NumberofElementsKnobValueChanged, true);
            app.NumberofElementsKnob.Value = 10;
            app.Button_6 = uibutton(app.SortingTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'minus.png';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.SortingTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_7.Icon = 'plus.png';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.SortingTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Icon = 'minus.png';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.SortingTab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Icon = 'plus.png';
            app.Button_9.Text = '';
            app.UIAxes = uiaxes(app.SortingTab);
            xlabel(app.UIAxes, 'Number of Elements')
            ylabel(app.UIAxes, 'Array')
            app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100];
            app.UIAxes.YTickLabel = {'10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.ComparisionTabs = uitab(app.TabGroup);
            app.ComparisionTabs.SizeChangedFcn = createCallbackFcn(app, @ComparisionTabsSizeChanged, true);
            app.ComparisionTabs.Title = 'Comparision';
            app.MaxRangeEditField_2 = uieditfield(app.ComparisionTabs, 'numeric');
            app.MaxRangeEditField_2.Value = 1000;
            app.NumberofElementsEditField_2 = uieditfield(app.ComparisionTabs, 'numeric');
            app.NumberofElementsEditField_2.Limits = [1 Inf];
            app.NumberofElementsEditField_2.Value = 10;
            app.InsertionSortsecEditField = uieditfield(app.ComparisionTabs, 'numeric');
            app.MergeSortsecEditField = uieditfield(app.ComparisionTabs, 'numeric');
            app.BubbleSortsecEditField = uieditfield(app.ComparisionTabs, 'numeric');
            app.QuickSortsecEditField = uieditfield(app.ComparisionTabs, 'numeric');
            app.HeapSortsecEditField = uieditfield(app.ComparisionTabs, 'numeric');
            app.CountingSortsecEditField = uieditfield(app.ComparisionTabs, 'numeric');
            app.RadixSortsecEditField = uieditfield(app.ComparisionTabs, 'numeric');
            app.BucketSortsecEditField = uieditfield(app.ComparisionTabs, 'numeric');
            app.ResultsLabel = uilabel(app.ComparisionTabs);
            app.ResultsLabel.Text = '   Results';
            app.StartButton = uibutton(app.ComparisionTabs, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SortedArrayTextAreaLabel = uilabel(app.ComparisionTabs);
            app.SortedArrayTextAreaLabel.Text = 'Sorted Array';
            app.SortedArrayTextArea = uitextarea(app.ComparisionTabs);
            app.IterationEditFieldLabel = uilabel(app.ComparisionTabs);
            app.IterationEditFieldLabel.Text = 'Iteration';
            app.IterationEditField = uieditfield(app.ComparisionTabs, 'numeric');
            app.IterationEditField.Limits = [1 Inf];
            app.IterationEditField.Value = 50;
            app.MaxRangeEditFieldLabel_2 = uilabel(app.ComparisionTabs);
            app.MaxRangeEditFieldLabel_2.Text = 'Max Range';
            app.NumberofElementsEditFieldLabel_2 = uilabel(app.ComparisionTabs);
            app.NumberofElementsEditFieldLabel_2.Text = {'Number of'; 'Elements'};
            app.MaxRangeEditFieldLabel_3 = uilabel(app.ComparisionTabs);
            app.MaxRangeEditFieldLabel_3.Text = 'Max Range';
            app.NumberofElementsEditFieldLabel_3 = uilabel(app.ComparisionTabs);
            app.NumberofElementsEditFieldLabel_3.Text = {'Number of'; 'Elements'};
            app.InsertionSortsecLabel = uilabel(app.ComparisionTabs);
            app.InsertionSortsecLabel.Text = 'Insertion Sort (sec)';
            app.MergeSortsecEditFieldLabel = uilabel(app.ComparisionTabs);
            app.MergeSortsecEditFieldLabel.Text = 'Merge Sort (sec)';
            app.BubbleSortsecEditFieldLabel = uilabel(app.ComparisionTabs);
            app.BubbleSortsecEditFieldLabel.Text = 'Bubble Sort (sec)';
            app.QuickSortsecEditFieldLabel = uilabel(app.ComparisionTabs);
            app.QuickSortsecEditFieldLabel.Text = 'Quick Sort (sec)';
            app.HeapSortsecEditFieldLabel = uilabel(app.ComparisionTabs);
            app.HeapSortsecEditFieldLabel.Text = 'Heap Sort (sec)';
            app.CountingSortsecEditFieldLabel = uilabel(app.ComparisionTabs);
            app.CountingSortsecEditFieldLabel.Text = 'Counting Sort (sec)';
            app.RadixSortsecEditFieldLabel = uilabel(app.ComparisionTabs);
            app.RadixSortsecEditFieldLabel.Text = 'Radix Sort (sec)';
            app.BucketSortsecEditFieldLabel = uilabel(app.ComparisionTabs);
            app.BucketSortsecEditFieldLabel.Text = 'Bucket Sort (sec)';
            app.UIAxes_2 = uiaxes(app.ComparisionTabs);
            xlabel(app.UIAxes_2, 'N(length of array)')
            ylabel(app.UIAxes_2, 'Time(Sec)')
            app.UIAxes_2.XLim = [-10 70];
            app.UIAxes_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.RandomizedTab = uitab(app.TabGroup);
            app.RandomizedTab.Title = 'Randomized';
            app.FindButton = uibutton(app.RandomizedTab, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Text = 'Find';
            app.title3 = uilabel(app.RandomizedTab);
            app.title3.Text = 'Randomized divide-and-conquer algorithm';
            app.NumberofElementsEditField_3 = uieditfield(app.RandomizedTab, 'numeric');
            app.NumberofElementsEditField_3.ValueDisplayFormat = '%.0f';
            app.NumberofElementsEditField_3.Value = 15;
            app.MaxRangeEditField_3 = uieditfield(app.RandomizedTab, 'numeric');
            app.MaxRangeEditField_3.ValueDisplayFormat = '%.0f';
            app.MaxRangeEditField_3.Value = 100;
            app.SearchedindexEditField = uieditfield(app.RandomizedTab, 'numeric');
            app.SearchedindexEditField.ValueDisplayFormat = '%.0f';
            app.SearchedindexEditField.Value = 5;
            app.AnswerTextAreaLabel = uilabel(app.RandomizedTab);
            app.AnswerTextAreaLabel.Text = 'Answer';
            app.AnswerTextArea = uitextarea(app.RandomizedTab);
            app.ArraySizeKnob_2 = uiknob(app.RandomizedTab, 'continuous');
            app.ArraySizeKnob_2.Limits = [0 1000];
            app.ArraySizeKnob_2.ValueChangedFcn = createCallbackFcn(app, @ArraySizeKnob_2ValueChanged, true);
            app.ArraySizeKnob_2.Value = 100;
            app.MaxRangeEditField_3Label = uilabel(app.RandomizedTab);
            app.MaxRangeEditField_3Label.Text = 'Max Range';
            app.ArraySizeKnob_3 = uiknob(app.RandomizedTab, 'continuous');
            app.ArraySizeKnob_3.Limits = [0 1000];
            app.ArraySizeKnob_3.ValueChangedFcn = createCallbackFcn(app, @ArraySizeKnob_3ValueChanged, true);
            app.ArraySizeKnob_3.MinorTicks = [0 20 40 60 80 100 120 140 160 180 200 220 240 260 280 300 320 340 360 380 400 420 440 460 480 500 520 540 560 580 600 620 640 660 680 700 720 740 760 780 800 820 840 860 880 900 920 940 960 980 1000];
            app.ArraySizeKnob_3.Value = 100;
            app.NumberofElementsEditField_3Label = uilabel(app.RandomizedTab);
            app.NumberofElementsEditField_3Label.Text = 'Number of Elements';
            app.ArrayTextAreaLabel = uilabel(app.RandomizedTab);
            app.ArrayTextAreaLabel.Text = 'Array';
            app.ArrayTextArea = uitextarea(app.RandomizedTab);
            app.ArraySizeKnob_4 = uiknob(app.RandomizedTab, 'continuous');
            app.ArraySizeKnob_4.Limits = [0 1000];
            app.ArraySizeKnob_4.ValueChangedFcn = createCallbackFcn(app, @ArraySizeKnob_4ValueChanged, true);
            app.ArraySizeKnob_4.Value = 100;
            app.SearchedindexEditFieldLabel = uilabel(app.RandomizedTab);
            app.SearchedindexEditFieldLabel.Text = 'Searched index';
            app.FibonacciTab = uitab(app.TabGroup);
            app.FibonacciTab.Title = 'Fibonacci';
            app.SortButton = uibutton(app.FibonacciTab, 'push');
            app.SortButton.ButtonPushedFcn = createCallbackFcn(app, @SortButtonPushed, true);
            app.SortButton.Text = 'Sort';
            app.FibonacciButton = uibutton(app.FibonacciTab, 'push');
            app.FibonacciButton.ButtonPushedFcn = createCallbackFcn(app, @FibonacciButtonPushed, true);
            app.FibonacciButton.Text = 'Fibonacci';
            app.BinarysearchinfibonacciButton = uibutton(app.FibonacciTab, 'push');
            app.BinarysearchinfibonacciButton.ButtonPushedFcn = createCallbackFcn(app, @BinarysearchinfibonacciButtonPushed, true);
            app.BinarysearchinfibonacciButton.Text = 'Binary search in fibonacci';
            app.BINARYSEARCHLabel = uilabel(app.FibonacciTab);
            app.BINARYSEARCHLabel.Text = 'BINARY SEARCH';
            app.FIBONACCILabel = uilabel(app.FibonacciTab);
            app.FIBONACCILabel.Text = 'FIBONACCI';
            app.AnswerButton = uibutton(app.FibonacciTab, 'push');
            app.AnswerButton.ButtonPushedFcn = createCallbackFcn(app, @AnswerButtonPushed, true);
            app.AnswerButton.Text = 'Answer';
            app.RangeEditField = uieditfield(app.FibonacciTab, 'numeric');
            app.RangeEditField.ValueDisplayFormat = '%.0f';
            app.RangeEditField.Value = 100;
            app.ElementsEditField = uieditfield(app.FibonacciTab, 'numeric');
            app.ElementsEditField.ValueDisplayFormat = '%.0f';
            app.ElementsEditField.Value = 10;
            app.RandomarrayTextAreaLabel = uilabel(app.FibonacciTab);
            app.RandomarrayTextAreaLabel.Text = 'Random array ';
            app.RandomarrayTextArea = uitextarea(app.FibonacciTab);
            app.NumberEditFieldLabel = uilabel(app.FibonacciTab);
            app.NumberEditFieldLabel.Text = 'Number';
            app.NumberEditField = uieditfield(app.FibonacciTab, 'numeric');
            app.SortedarrayTextAreaLabel = uilabel(app.FibonacciTab);
            app.SortedarrayTextAreaLabel.Text = 'Sorted array';
            app.SortedarrayTextArea = uitextarea(app.FibonacciTab);
            app.AnswerTextArea_2Label = uilabel(app.FibonacciTab);
            app.AnswerTextArea_2Label.Text = 'Answer:';
            app.AnswerTextArea_2 = uitextarea(app.FibonacciTab);
            app.AnswerTextArea_3Label = uilabel(app.FibonacciTab);
            app.AnswerTextArea_3Label.Text = 'Answer';
            app.AnswerTextArea_3 = uitextarea(app.FibonacciTab);
            app.NthnumberofFibonacciEditFieldLabel = uilabel(app.FibonacciTab);
            app.NthnumberofFibonacciEditFieldLabel.Text = {'N''th number of '; 'Fibonacci'};
            app.NthnumberofFibonacciEditField = uieditfield(app.FibonacciTab, 'numeric');
            app.FibonacciEditFieldLabel = uilabel(app.FibonacciTab);
            app.FibonacciEditFieldLabel.Text = 'Fibonacci';
            app.FibonacciEditField = uieditfield(app.FibonacciTab, 'numeric');
            app.AnswerTextArea_4Label = uilabel(app.FibonacciTab);
            app.AnswerTextArea_4Label.Text = 'Answer';
            app.AnswerTextArea_4 = uitextarea(app.FibonacciTab);
            app.ArraySizeKnob_5 = uiknob(app.FibonacciTab, 'continuous');
            app.ArraySizeKnob_5.Limits = [0 1000];
            app.ArraySizeKnob_5.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.ArraySizeKnob_5.MajorTickLabels = {'0', '100', '200', '300', '400', '500', '600', '700', '800', '900', '1000'};
            app.ArraySizeKnob_5.ValueChangedFcn = createCallbackFcn(app, @ArraySizeKnob_5ValueChanged, true);
            app.ArraySizeKnob_5.MinorTicks = [0 20 40 60 80 100 120 140 160 180 200 220 240 260 280 300 320 340 360 380 400 420 440 460 480 500 520 540 560 580 600 620 640 660 680 700 720 740 760 780 800 820 840 860 880 900 920 940 960 980 1000];
            app.ArraySizeKnob_5.Value = 100;
            app.ArraySizeKnob_6 = uiknob(app.FibonacciTab, 'continuous');
            app.ArraySizeKnob_6.Limits = [0 1000];
            app.ArraySizeKnob_6.ValueChangedFcn = createCallbackFcn(app, @ArraySizeKnob_6ValueChanged, true);
            app.ArraySizeKnob_6.Value = 100;
            app.RangeEditFieldLabel = uilabel(app.FibonacciTab);
            app.RangeEditFieldLabel.Text = 'Range';
            app.ElementsEditFieldLabel = uilabel(app.FibonacciTab);
            app.ElementsEditFieldLabel.Text = 'Elements';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.Button_5 = uibutton(app.HelpTab, 'push');
            app.Button_5.Icon = '1d5ebd5a-7bef-4aa4-ab48-c893eebc041e.png';
            app.Button_5.Text = '';
            app.StudentnameEgeAlpTrkyenerNumber170403031ProfessorvOLLabel = uilabel(app.HelpTab);
            app.StudentnameEgeAlpTrkyenerNumber170403031ProfessorvOLLabel.Text = {'Student name: Ege Alp Turkyener'; 'Number: 170403031'; 'Professor: Volkan Kilic'};
            app.Thisprogramhas4tabs1SortingThistabLabel = uilabel(app.HelpTab);
            app.Thisprogramhas4tabs1SortingThistabLabel.Text = {'This program has 4 tabs.'; '1)Sorting: Visualize sorting methods using bars.'; '2)Comparision: Compare running times of different sorting methods. Show it on graph.'; '3)Randomized: Using divide and conquer algorithm to find searched index numbers.'; '4)Fibonacci: This tab has 3 different sub-works. Does binary search, finds fibonacci numbers and '; 'using binary search finds numbers in fibonacci series.'; '                    '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proje

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end